classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        DoubleStopCheckBox  matlab.ui.control.CheckBox
        IgnitionCheckBox    matlab.ui.control.CheckBox
        StopButton          matlab.ui.control.Button
        StartButton         matlab.ui.control.Button
        LoadButton          matlab.ui.control.Button
        plot                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        %  starts here, insert everything that we require.

        modelR3; % insert UR3 here

        % Insert second robot here

        q0 = [0,0,0,0,0,0];
        qUR3 = [0,pi,pi/2,0,0,-pi];

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function init(app, varargin)
            % Code to run once GUI is loaded successfully
            ax = gca;

            % Make, save and plot some robots            
            
            app.modelR3 = UR3;
            % app.modelR3.base = transl([0.5,0,0]);
            app.modelR3.model.animate(app.q0);
            drawnow();
            hold on;
            % plot(app.modelR3,app.qUR3,'workspace',[-2 2 -2 2 -0.5 2])            
            % hold on;         

            % app.modelUR5 = UR5;
            % app.modelUR5.model.base = transl([-0.5,0,0]);
            % app.modelUR5.model.animate(app.qrUR5);
            % drawnow();

            % Delete the green floor surface (need to count backwards
            % because once it is deleted the array is compressed)
            for i = size(ax.Children,1):-1:1
                if strcmp(ax.Children(i).Type,'surface')
                    delete(ax.Children(i));
                end
            end
            
            % Turn off this load button and enable the move button
            app.StartButton.Enable = true;
            % app.MakeLoadedUR5MoveButton.Enable = true;
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.DoubleStopCheckBox.Value=1;
            app.IgnitionCheckBox.Value=0;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)

                if app.DoubleStopCheckBox.Value==0 && app.IgnitionCheckBox.Value==1
                        qMatrix=jtraj(app.q0,app.qUR3,50);
                        for robotStepIndex = 1:size(qMatrix,1)
                            
                            app.modelR3.model.animate(qMatrix(robotStepIndex,:));
                            drawnow();
                        end
                end
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            % mdl_puma560;
            % q=[1,3,5,2,1,1];
            % p560.plot(q);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.plot = uiaxes(app.UIFigure);
            xlabel(app.plot, 'X')
            ylabel(app.plot, 'Y')
            app.plot.Tag = 'axes1';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.IgnitionCheckBox = uicheckbox(app.UIFigure);
            app.IgnitionCheckBox.Text = 'Ignition';
            app.DoubleStopCheckBox = uicheckbox(app.UIFigure);
            app.DoubleStopCheckBox.Text = 'Double Stop';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)init(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end